classdef bmsMatlab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        PrimaryTab                   matlab.ui.container.Tab
        TempTable                    matlab.ui.control.Table
        VoltageTable                 matlab.ui.control.Table
        Panel                        matlab.ui.container.Panel
        HideDeltaButton              matlab.ui.control.StateButton
        ErrorLabel                   matlab.ui.control.Label
        ChargerStatusLabel           matlab.ui.control.Label
        ICMaxTempLabel               matlab.ui.control.Label
        ChargerCurrentLabel          matlab.ui.control.Label
        TSCurrentLabel               matlab.ui.control.Label
        MaxTempLabel                 matlab.ui.control.Label
        MinTempLabel                 matlab.ui.control.Label
        DeltaVoltLabel               matlab.ui.control.Label
        MaxVoltLabel                 matlab.ui.control.Label
        MinVoltLabel                 matlab.ui.control.Label
        AvgVoltLabel                 matlab.ui.control.Label
        PackVoltLabel                matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        LogCSVCheckBox               matlab.ui.control.CheckBox
        FloatCheckBox                matlab.ui.control.CheckBox
        BalanceCheckBox              matlab.ui.control.CheckBox
        ChargeCurrentFieldEditField  matlab.ui.control.NumericEditField
        ChargeCurrentFieldEditFieldLabel  matlab.ui.control.Label
        BaudDropdownDropDown         matlab.ui.control.DropDown
        BaudDropdownDropDownLabel    matlab.ui.control.Label
        PortField                    matlab.ui.control.EditField
        PortFieldEditFieldLabel      matlab.ui.control.Label
        ChargeButton                 matlab.ui.control.StateButton
        ConnectButton                matlab.ui.control.StateButton
        SummaryTab                   matlab.ui.container.Tab
        ICTempTable                  matlab.ui.control.Table
        SegmentTable                 matlab.ui.control.Table
    end

    
  properties (Access = private)
   ser                 % serial port
    readTimer           % timer για ανάγνωση CAN
    guiTimer            % timer για ενημέρωση GUI

    connected logical = false

    VOLTAGES    double   % 12x12 cell voltages
    TEMPS       double   % 12x12 temperatures
    MAXICTemps  double   % 12 IC temp values
    dischFlags  double   % 12x12 flags (0/1) για discharge (μελλοντικά)
    MAX_VOLTAGE_THRESHOLD double = 4.15;   % V  (όριο για stop charge)
    MIN_VOLTAGE_THRESHOLD double = 2.7;    % V  (κάτω όριο για stop)
    TEMP_WARN_LIMIT      double = 59;      % °C (όριο θερμοκρασίας)
    lastRxTime double = 0
    CAN_TIMEOUT double = 3.0
    lostConFlag double = 0
    t0 double = 0;   % timebase για CSV timestamps
% ===== Extra decimal toggle =====
   showExtraDecimal logical = false;

   voltDecBase double = 3;   % voltages default decimals
   tempDecBase double = 1;   % temperatures default decimals
   currDecBase double = 2;   % currents default decimals

    hbTimer             % timer για heartbeat προς BMS/CANdapter
    csvFile char = 'bmsLoggingData.csv'
    csvFID double = -1          % file id για fopen (>=0 σημαίνει ανοιχτό)
    csvHeaderWritten logical = false

    ivtCurrent double = 0        % mA από IVT
    chargerCurrent double = 0    % A από φορτιστή
    chargerStatusFlag double = -1
    chargerConnected logical = false

    chargingState double = 0   % 0=OFF, 1=ON
    strHex char = '64'         % default current HEX

    TEMP_SENSOR_MAP = 1:10;
    lastError char = 'None';   % για τα error codes του BMS
        % ===== WiFi/UDP =====
    udpRx                       % udpport object
    wifiTimer                   % timer για UDP polling
    wifiEnabled logical = false

    wifiLocalPort double = 5005
    wifiRemoteIP   char  = "192.168.4.1"   % αν έχεις ESP32 AP, αλλιώς βάλε IP του sender
    wifiRemotePort double = 5006           % port που ακούει ο telemetry node (μόνο αν θες send)
    
    chargerMode logical = false            % ΑΛΛΑΖΕΙ μόνο από BMS/σήμα, ΟΧΙ από WiFi command
    % ===== Extra decimal toggle =====
    
  end
    
      % App initialization


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn3(app)
   

           % ===== Αρχικοποίηση πινάκων BMS =====
        app.VOLTAGES   = NaN(12,10);
        app.TEMPS      = NaN(12,12);
        app.MAXICTemps = NaN(1,12);
        app.dischFlags = zeros(12,10);


        % ===== Κατάσταση σύνδεσης / φορτιστή =====
        app.connected         = false;
        app.chargerConnected  = false;
        app.chargingState     = 0;            % 0 = OFF
        app.ivtCurrent        = 0;
        app.chargerCurrent    = 0;
        app.chargerStatusFlag = -1;
        app.strHex            = '64';         % default hex
        app.lastError         = 'None';

        % ===== Αρχικά μηνύματα GUI =====
        app.StatusLabel.Text = "Disconnected";
        app.ErrorLabel.Text  = "";

        % ===== Γέμισε τα tables με αρχικά δεδομένα =====
        app.VoltageTable.Data = app.VOLTAGES;
        app.TempTable.Data    = app.TEMPS;

        % ===== Override locked table layout to 12x10 =====
        app.VoltageTable.ColumnName = arrayfun(@(i) sprintf("cell%d", i), 1:10, "UniformOutput", false);
        app.TempTable.ColumnName    = arrayfun(@(i) sprintf("T%d", i),     1:12, "UniformOutput", false);

        app.VoltageTable.RowName = arrayfun(@(i) sprintf("IC%d", i), 1:12, "UniformOutput", false);
        app.TempTable.RowName = [];

        app.ICTempTable.ColumnName = arrayfun(@(i) sprintf("IC%d", i), 1:12, "UniformOutput", false);
        app.ICTempTable.RowName = {};
       % ===== Resize tables to remove scrollbars =====

      
   % ===== Auto column width =====
       app.VoltageTable.ColumnWidth = '1x';
       app.TempTable.ColumnWidth    = '1x';
       app.ICTempTable.ColumnWidth  = '1x';
   % Font tweak
       app.VoltageTable.FontSize = 10;
       app.TempTable.FontSize    = 10;
        % Summary tables 
        app.SegmentTable.Data = zeros(1,12);
        app.ICTempTable.Data  = zeros(1,12);
        app.ConnectButton.ValueChangedFcn = @(src,event) ConnectButtonValueChanged(app,event);
        app.TempTable.CellEditCallback   = @(src,event) TempTableCellEdit(app,event);
        app.ChargeButton.ValueChangedFcn = @(src,event) ChargeButtonValueChanged(app,event);  
        app.LogCSVCheckBox.ValueChangedFcn = @(src,event) LogCSVCheckBoxValueChanged(app,event);


       % ===== FloatCheckBox => "emoji toggle" for extra decimals =====
       app.FloatCheckBox.Text = "🙂";         % OFF εμφάνιση
       app.FloatCheckBox.Value = false;       % ξεκινά OFF
       app.FloatCheckBox.Tooltip = "Toggle extra decimal precision";
       app.FloatCheckBox.ValueChangedFcn = @(src,event) FloatEmojiToggled(app);
       app.DeltaVoltLabel.Visible = "off";
% ===== Hide old Δ button (locked UI workaround) =====
       try
        app.HideDeltaButton.Visible = "off";
        app.HideDeltaButton.Enable  = "off";
      catch
      end


        % ======================================================
        %   TIMERS
        % ======================================================
        app.readTimer = timer( ...
            "ExecutionMode", "fixedSpacing", ...
            "Period", 0.02, ...             % 20ms
            "TimerFcn", @(~,~) serialReadTick(app) );

        app.guiTimer = timer( ...
            "ExecutionMode", "fixedSpacing", ...
            "Period", 0.10, ...             % 10Hz
            "TimerFcn", @(~,~) guiTick(app) ); 
     app.lastRxTime = tic;

    % Heartbeat timer 
    app.hbTimer = timer( ...
        "ExecutionMode","fixedSpacing", ...
        "Period", 0.2, ...                 
        "TimerFcn", @(~,~) heartbeatTick(app) );
        % ===== WiFi timer =====
        app.wifiTimer = timer( ...
            "ExecutionMode","fixedSpacing", ...
            "Period", 0.02, ...
            "TimerFcn", @(~,~) wifiReadTick(app) );

            
    end 
    
    % ===== MAIN GRID (Figure responsive layout) =====
%mainGrid = uigridlayout(app.UIFigure, [1 1]);
%mainGrid.RowHeight = {'1x'};
%mainGrid.ColumnWidth = {'1x'};

% Βάλε το TabGroup μέσα στο grid
%app.TabGroup.Parent = mainGrid;
%app.TabGroup.Layout.Row = 1;
%app.TabGroup.Layout.Column = 1;

 % ===== PRIMARY TAB GRID (locked-safe) =====
%primaryGrid = uigridlayout(app.PrimaryTab,[2 2]);

%primaryGrid.RowHeight = {150, '1x'};
%primaryGrid.ColumnWidth = {'1x','1x'};
%primaryGrid.Padding = [5 5 5 5];
%primaryGrid.RowSpacing = 5;
%primaryGrid.ColumnSpacing = 5;
    
      % ======================================================
    %   GUI UPDATE FUNCTION
    % ======================================================
         % ======================================================
        %   SERIAL READ TICK
        % ======================================================
function serialReadTick(app)

    if ~app.connected
        return;
    end

    try
        while app.ser.NumBytesAvailable > 0
            line = readline(app.ser);
            line = strtrim(line);

            if line == ""
                continue;
            end

            % ===== SLCAN frames =====
            if startsWith(line,"t") || startsWith(line,"T")
                [ok, ~, canId, data] = parseSlcanLine(app, line);
                if ok
                    app.lastRxTime = tic;
                    decodeCanFrame(app, canId, data);
                end
                continue;
            end

            % ===== CSV-like messages =====
            parts = split(line, ",");
            if numel(parts) < 2
                continue;
            end

            prefix = parts{1};

            switch prefix

                case "V"
                    if numel(parts) < 4, continue; end
                    seg = str2double(parts{2});
                    cell = str2double(parts{3});
                    val = str2double(parts{4});
                    if seg>=1 && seg<=12 && cell>=1 && cell<=10
                     app.VOLTAGES(seg,cell) = val;
                    end


                case "T"
                    if numel(parts) < 4, continue; end
                    seg = str2double(parts{2});
                    cell = str2double(parts{3});
                    val = str2double(parts{4});
                    if seg>=1 && seg<=12 && cell>=1 && cell<=12
                     app.TEMPS(seg,cell) = val;
                    end


                case "IC"
                    ic = str2double(parts{2});
                    val = str2double(parts{3});
                    if ic>=1 && ic<=12
                        app.MAXICTemps(ic) = val;
                    end

                case "IVT"
                    app.ivtCurrent = str2double(parts{2});

                case "CC"
                    app.chargerCurrent = str2double(parts{2});

                case "CH"
                    app.chargerConnected = logical(str2double(parts{2}));
                    app.chargerStatusFlag = str2double(parts{3});

                case "ERR"
                    code = str2double(parts{2});
                    switch code
                        case 0, app.lastError = "ISOSPI";
                        case 1, app.lastError = "Voltages";
                        case 2, app.lastError = "Temps";
                        case 3, app.lastError = "Current Sensor";
                        otherwise, app.lastError = "Generic";
                    end
                    app.ErrorLabel.Text = "Last Error: " + app.lastError;
            end
        end
  

        app.safetyCheck();

        % ===== Timeout =====
       if toc(app.lastRxTime) > app.CAN_TIMEOUT
    forceDisconnect(app, "timeout");
    return;
end
 

    catch ME
        app.ErrorLabel.Text = ME.message;
    end
end        
function wifiReadTick(app)

    if ~app.connected || ~app.wifiEnabled || isempty(app.udpRx)
        return;
    end

    try
        while app.udpRx.NumDatagramsAvailable > 0
            d = read(app.udpRx, 1, "uint8");
            line = strtrim(string(char(d.Data(:)')));

            if line == ""
                continue;
            end

            app.lastRxTime = tic;   % όπως στο serial
            decodeWifiLine(app, line);
        end

        app.safetyCheck();

        if toc(app.lastRxTime) > app.CAN_TIMEOUT
            forceDisconnect(app, "wifi timeout");
            return;
        end
       

    catch ME
        app.ErrorLabel.Text = "WiFi RX: " + string(ME.message);
    end
end

function decodeWifiLine(app, line)

    % Χρησιμοποιεί το ίδιο format που ήδη έχεις: "V,seg,cell,val"
    parts = split(line, ",");
    if numel(parts) < 2
        return;
    end

    prefix = strtrim(parts{1});

    switch prefix
        case "V"
            if numel(parts) < 4, return; end
            seg  = str2double(parts{2});
            cell = str2double(parts{3});
            val  = str2double(parts{4});
            if seg>=1 && seg<=12 && cell>=1 && cell<=10
                app.VOLTAGES(seg,cell) = val;
            end

        case "T"
            if numel(parts) < 4, return; end
            seg  = str2double(parts{2});
            cell = str2double(parts{3});
            val  = str2double(parts{4});
            if seg>=1 && seg<=12 && cell>=1 && cell<=12
                app.TEMPS(seg,cell) = val;
            end

        case "IC"
            if numel(parts) < 3, return; end
            ic  = str2double(parts{2});
            val = str2double(parts{3});
            if ic>=1 && ic<=12
                app.MAXICTemps(ic) = val;
            end

        case "IVT"
            if numel(parts) < 2, return; end
            app.ivtCurrent = str2double(parts{2});

        case "CC"
            if numel(parts) < 2, return; end
            app.chargerCurrent = str2double(parts{2});

        case "CH"
            if numel(parts) < 3, return; end
            app.chargerConnected  = logical(str2double(parts{2}));
            app.chargerStatusFlag = str2double(parts{3});

        case "MODE"
            % ΠΡΟΣΟΧΗ: καλό είναι αυτό να έρχεται από BMS/CAN, όχι από WiFi "command"
            if numel(parts) < 2, return; end
            m = upper(strtrim(parts{2}));
            app.chargerMode = (m == "CHARGE");

        case "ERR"
            if numel(parts) < 2, return; end
            code = str2double(parts{2});
            switch code
                case 0, app.lastError = "ISOSPI";
                case 1, app.lastError = "Voltages";
                case 2, app.lastError = "Temps";
                case 3, app.lastError = "Current Sensor";
                otherwise, app.lastError = "Generic";
            end
            app.ErrorLabel.Text = "Last Error: " + app.lastError;
    end
end
function heartbeatTick(app)

    if ~app.connected || isempty(app.ser)
        return;
    end

    try
        balanceFlag = app.BalanceCheckBox.Value;
        on_off = '1';
        if app.chargingState == 1
            on_off = '0';
        end

        hb = sprintf("T1502%s%d%s", app.strHex, balanceFlag, on_off);
        writeline(app.ser, hb);
        writeline(app.ser, "L1");

    catch
        
    end
end
function sendChargeCommand(app, turnOn)
    % turnOn = 1 → ON, turnOn = 0 → OFF

    if isempty(app.ser)
        return;
    end

    balanceFlag = app.BalanceCheckBox.Value;

    if turnOn == 1
        on_off = '0'; % ON
    else
        on_off = '1'; % OFF
    end

    cmd = sprintf("T1502%s%d%s", app.strHex, balanceFlag, on_off);

    try
        writeline(app.ser, cmd);
        writeline(app.ser, "L1");
    catch
        % άστο
    end
end


function forceDisconnect(app, reasonText)

    app.connected = false;

    try
        if isvalid(app.StatusLabel)
            app.StatusLabel.Text = "Disconnected (" + string(reasonText) + ")";
        end
    catch
    end

    % σταμάτα timers
    try
        if ~isempty(app.readTimer) && isvalid(app.readTimer), stop(app.readTimer); end
    catch, end
    try
        if ~isempty(app.guiTimer) && isvalid(app.guiTimer), stop(app.guiTimer); end
    catch, end
    try
        if ~isempty(app.hbTimer) && isvalid(app.hbTimer), stop(app.hbTimer); end
    catch, end

    % κλείσε serial
    try
        if ~isempty(app.ser)
            clear app.ser
        end
    catch
    end

    % κλείσε CSV
    closeCsv(app);

    % γύρνα κουμπιά
    try
        if isvalid(app.ConnectButton), app.ConnectButton.Value = 0; end
    catch, end
    try
        if isvalid(app.ChargeButton), app.ChargeButton.Value = 0; end
    catch, end

    app.chargingState = 0;
end

function [ok, isExt, canId, dataBytes] = parseSlcanLine(app, rawLine)

    ok = false; isExt = false; canId = 0; dataBytes = uint8([]);

    s = char(rawLine);

    if s(1) == 't'
        canId = hex2dec(s(2:4));
        dlc   = hex2dec(s(5));
        dataHex = s(6:5+2*dlc);
    elseif s(1) == 'T'
        isExt = true;
        canId = hex2dec(s(2:9));
        dlc   = hex2dec(s(10));
        dataHex = s(11:10+2*dlc);
    else
        return;
    end

    dataBytes = uint8(zeros(1,dlc));
    for i = 1:dlc
        dataBytes(i) = uint8(hex2dec(dataHex(2*i-1:2*i)));
    end

    ok = true;
end
function decodeCanFrame(app, canId, data)

    switch canId

        % ==========================
        % 0x700: Voltages + flags + charger bits
        % ==========================
        case hex2dec('700')
            if numel(data) < 8, return; end

            b1 = data(1);
            b2 = data(2);

            ic   = bitand(b1, 15) + 1;   % 1..12
            base = bitand(b2, 15) + 1;   % 1..12

            % 3-bit flags στο high nibble του b1 (όπως Python)
            flags = bitand(bitshift(b1, -4), 7);

            for i = 0:2
                idx = mod((base-1) + i, 10) + 1;

                % discharge/balance flag για το κάθε cell
                app.dischFlags(ic, idx) = bitand(bitshift(flags, -i), 1);

                hi = data(3 + 2*i);
                lo = data(4 + 2*i);
                raw16 = uint16(hi) * 256 + uint16(lo);

                rawSigned = typecast(uint16(raw16), 'int16');
                v = (double(rawSigned) + 10000) * 0.000150;
                app.VOLTAGES(ic, idx) = v;
            end

            % Charger status bits από high nibble του b2
            hb = bitshift(b2, -4);
            app.chargerConnected  = logical(bitand(hb, 1));
            app.chargerStatusFlag = bitand(bitshift(hb, -1), 3);

        % ==========================
        % 0x701: Temps + max IC temp
        % ==========================
        case hex2dec('701')
            if numel(data) < 8, return; end

            b1 = data(1);
            b2 = data(2);

            ic   = bitand(b1, 15) + 1;
            base = bitand(b2, 15) + 1;

            maxIc = bitor(bitshift(bitshift(b1, -4), 4), bitshift(b2, -4));
            app.MAXICTemps(ic) = double(maxIc);

           
        % Temps bytes: byte3.. as (value-100), write to 10 columns
       
      count = min(numel(data)-2, 12 - (base-1));
      for i = 1:count
         idx = base + (i-1);   % 1..12
         t = double(data(2+i)) - 100;
        if idx>=1 && idx<=12
        app.TEMPS(ic, idx) = t;
        end
      end

        % ==========================
        % 0x521: IVT current (bytes 5..8 signed int32)
        % ==========================
        case hex2dec('521')
            if numel(data) < 8, return; end
            raw32 = uint32(data(5))*2^24 + uint32(data(6))*2^16 + uint32(data(7))*2^8 + uint32(data(8));
            app.ivtCurrent = double(typecast(uint32(raw32),'int32')); % mA

        % ==========================
        % 0x102: Charger current
        % ==========================
        case hex2dec('102')
            if numel(data) < 1, return; end
            app.chargerCurrent = double(data(1)) / 10.0;

        % ==========================
        % 0x101: Fault → stop charge
        % ==========================
        case hex2dec('101')
            if numel(data) < 1, return; end

            code = double(data(1));
            switch code
                case 0, app.lastError = "ISOSPI";
                case 1, app.lastError = "Voltages";
                case 2, app.lastError = "Temps";
                case 3, app.lastError = "Current Sensor";
                otherwise, app.lastError = "Generic";
            end
            app.ErrorLabel.Text = "Last Error: " + app.lastError;

            % Αν φορτίζεις → σταμάτα αμέσως
            if app.chargingState == 1
                app.chargingState = 0;
                if isvalid(app.ChargeButton)
                    app.ChargeButton.Value = 0;
                end
                sendChargeCommand(app, 0); % OFF
            end

    end

end
function LogCSVCheckBoxValueChanged(app, event)
    if app.LogCSVCheckBox.Value
        openCsv(app);
    else
        closeCsv(app);
    end
end
function openCsv(app)

    % αν είναι ήδη ανοιχτό, μην το ξανανοίγεις
    if app.csvFID >= 0
        return;
    end

    path = fullfile(pwd, app.csvFile);

    % Αν δεν υπάρχει, θα γραφτεί header
    newFile = ~isfile(path);

    [fid, msg] = fopen(path, "a");
    if fid < 0
        app.ErrorLabel.Text = "CSV open failed: " + string(msg);
        app.LogCSVCheckBox.Value = 0;
        return;
    end

    app.csvFID = fid;

    if newFile
       fprintf(app.csvFID, ...
"timestamp,packV,minV,maxV,avgV,deltaV,minT,maxT,ivtA,chargerA,chargerConn,chargerStatus,lastError");

for ic = 1:12
    for c = 1:10
        fprintf(app.csvFID, ",V_%d_%d", ic, c);
    end
end

for ic = 1:12
    for c = 1:10
        fprintf(app.csvFID, ",T_%d_%d", ic, c);
    end
end

for ic = 1:12
    fprintf(app.csvFID, ",ICtemp_%d", ic);
end

fprintf(app.csvFID, "\n");
 
        app.csvHeaderWritten = true;
       % t0 double = 0   % timebase για timestamps

    end
end
function closeCsv(app)
    try
        if app.csvFID >= 0
            fclose(app.csvFID);
        end
    catch
    end

    app.csvFID = -1;
end

function logCsvRow(app)

    if ~app.LogCSVCheckBox.Value
        return;
    end

    if app.csvFID < 0
        openCsv(app);
        if app.csvFID < 0
            return;
        end
    end

    % ===== Flatten data =====
    V = reshape(app.VOLTAGES', 1, []);   % 12x10 -> 120 values
    T = reshape(app.TEMPS', 1, []);
    IC = app.MAXICTemps;

    voltAll = V(~isnan(V));
    tempAll = T(~isnan(T));

    packV = NaN; minV = NaN; maxV = NaN; avgV = NaN; deltaV = NaN;
    minT  = NaN; maxT = NaN;

    if ~isempty(voltAll)
        packV  = sum(voltAll);
        minV   = min(voltAll);
        maxV   = max(voltAll);
        avgV   = mean(voltAll);
        deltaV = maxV - minV;
    end

    if ~isempty(tempAll)
        minT = min(tempAll);
        maxT = max(tempAll);
    end

    ivtA = app.ivtCurrent / 1000.0;
    chgA = app.chargerCurrent;
    t = toc(app.t0);

    % ===== Write summary =====
    fprintf(app.csvFID, ...
        "%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.1f,%.1f,%.3f,%.3f,%d,%d,%s", ...
        t, packV, minV, maxV, avgV, deltaV, ...
        minT, maxT, ivtA, chgA, ...
        app.chargerConnected, app.chargerStatusFlag, ...
        string(app.lastError));

    % ===== Write all cells =====
    for i = 1:length(V)
        fprintf(app.csvFID, ",%.4f", V(i));
    end

    for i = 1:length(T)
        fprintf(app.csvFID, ",%.2f", T(i));
    end

    for i = 1:length(IC)
        fprintf(app.csvFID, ",%.1f", IC(i));
    end

    fprintf(app.csvFID, "\n");
end

        % ======================================================
        %   GUI UPDATE FUNCTION
        % ======================================================
        function guiTick(app)
            try
                % ===== Tables =====
                app.VoltageTable.Data = app.VOLTAGES;
                app.TempTable.Data    = app.TEMPS;

                % ===== Υπολογισμοί τάσεων =====
                voltAll = app.VOLTAGES(~isnan(app.VOLTAGES));
                tempAll = app.TEMPS(~isnan(app.TEMPS));
           % ===== Max VID =====
        if ~isempty(voltAll)
          [maxV, idx] = max(app.VOLTAGES(:));
          [seg, cell] = ind2sub(size(app.VOLTAGES), idx);
          app.MaxVIDLabel.Text = sprintf("Max V ID: IC%d / C%d", seg, cell);
       else
          app.MaxVIDLabel.Text = "Max V ID: --";
       end

% ===== Max TID =====
      if ~isempty(tempAll)
        [maxT, idx] = max(app.TEMPS(:));
        [seg, cell] = ind2sub(size(app.TEMPS), idx);
        app.MaxTIDLabel.Text = sprintf("Max T ID: IC%d / T%d", seg, cell);
     else
       app.MaxTIDLabel.Text = "Max T ID: --";
     end


                if ~isempty(voltAll)
                    packV = sum(voltAll);
                    app.PackVoltLabel.Text = sprintf("Pack: %.2f V", packV);

                    vDec = app.voltDecBase + double(app.showExtraDecimal);
                    fmtV = "Min: %." + string(vDec) + "f";
                    app.MinVoltLabel.Text = sprintf(char(fmtV), min(voltAll));

                    fmtV = "Max: %." + string(vDec) + "f";
                    app.MaxVoltLabel.Text = sprintf(char(fmtV), max(voltAll));

                    fmtV = "Avg: %." + string(vDec) + "f";
                    app.AvgVoltLabel.Text = sprintf(char(fmtV), mean(voltAll));

                    
                    if strcmp(app.DeltaVoltLabel.Visible,"on")
                      vDec = app.voltDecBase + double(app.showExtraDecimal);
                      fmt = "Δ: %." + string(vDec) + "f";
                      app.DeltaVoltLabel.Text = sprintf(char(fmt), max(voltAll)-min(voltAll));
                    end


                end

                % ===== Υπολογισμοί θερμοκρασιών =====
                if ~isempty(tempAll)
                     tDec = app.tempDecBase + double(app.showExtraDecimal);
                     fmtT = "MinT: %." + string(tDec) + "f";
                     app.MinTempLabel.Text = sprintf(char(fmtT), min(tempAll));

                     fmtT = "MaxT: %." + string(tDec) + "f";
                     app.MaxTempLabel.Text = sprintf(char(fmtT), max(tempAll));
               end

                % ===== Summary tables =====
                app.SegmentTable.Data = mean(app.VOLTAGES,2,'omitnan')';
                app.ICTempTable.Data  = app.MAXICTemps;

                % ===== TS Current / Charger Current =====
                cDec = 2 + double(app.showExtraDecimal);  % π.χ. 2->3
                fmtC = "TS Current: %." + string(cDec) + "f A";
                app.TSCurrentLabel.Text = sprintf(char(fmtC), app.ivtCurrent/1000);

                fmtC2 = "Charger Current: %." + string(cDec) + "f A";
                app.ChargerCurrentLabel.Text = sprintf(char(fmtC2), app.chargerCurrent);


                % ===== Max IC Temp summary =====
                maxICT = max(app.MAXICTemps);
                if maxICT > 0
                    app.ICMaxTempLabel.Text = sprintf("Max IC Temp: %.1f °C", maxICT);
                else
                    app.ICMaxTempLabel.Text = "Max IC Temp: -- °C";
                end

                % ===== Charger status text =====
                if ~app.connected
                    chargerTxt = "Charger status: --";
                else
                    if app.chargerStatusFlag == -1
                        chargerTxt = "Charger status: Unknown";
                    elseif app.chargerStatusFlag == 0
                        if app.chargerConnected
                            chargerTxt = "Charger status: OK";
                        else
                            chargerTxt = "Charger status: Disconnected";
                        end
                    elseif app.chargerStatusFlag == 1
                        chargerTxt = "Charger status: No DC Link";
                    else
                        chargerTxt = "Charger status: Fault";
                    end
                end
                app.ChargerStatusLabel.Text = chargerTxt;
                logCsvRow(app);

            catch
                
            end
        end
                % ======================================================
        %   SAFETY CHECK (όρια τάσης / θερμοκρασίας)
        % ======================================================
        function safetyCheck(app)
    if ~app.connected
        return;
    end

    voltAll = app.VOLTAGES(app.VOLTAGES > 0);
    tempAll = app.TEMPS(app.TEMPS > -40);

    overV  = ~isempty(voltAll) && max(voltAll) > app.MAX_VOLTAGE_THRESHOLD;
    underV = ~isempty(voltAll) && min(voltAll) < app.MIN_VOLTAGE_THRESHOLD;
    overT  = ~isempty(tempAll) && max(tempAll) > app.TEMP_WARN_LIMIT;

    if app.chargingState == 1 && (overV || underV || overT)
        app.chargingState = 0;

        try
            if ~isempty(app.ser)
                balanceFlag = app.BalanceCheckBox.Value;
                on_off = '1';
                cmdOff = sprintf("T1502%s%d%s", app.strHex, balanceFlag, on_off);
                writeline(app.ser, cmdOff);
                writeline(app.ser, "L1");
            end
        catch
        end

        if overV
            reason = "Cell over-voltage";
        elseif underV
            reason = "Cell under-voltage";
        else
            reason = "Cell over-temperature";
        end

        app.ErrorLabel.Text  = "Charge stopped (safety): " + reason;
        app.StatusLabel.Text = "Charging OFF (safety)";

        if isvalid(app.ChargeButton)
            app.ChargeButton.Value = 0;
        end
    end


app.t0 = tic;
% ===== Responsive layout (works even if createComponents is locked) =====

% Maximize window safely (no ScreenSize hacks)
try
    app.UIFigure.WindowState = "maximized";
catch
    % αν σε παλιά MATLAB δεν υπάρχει WindowState, το αφήνουμε
end


function FloatEmojiToggled(app)

    % ===== Toggle decimals =====
    app.showExtraDecimal = logical(app.FloatCheckBox.Value);

    if app.showExtraDecimal
        app.FloatCheckBox.Text = "😎";   % ON
        app.DeltaVoltLabel.Visible = "on";
    else
        app.FloatCheckBox.Text = "🙂";   % OFF
        app.DeltaVoltLabel.Visible = "off";
    end

    % refresh GUI
    try
        guiTick(app);
    catch
    end
end

% ===== MAIN GRID (Figure layout) =====
mainGrid = uigridlayout(app.UIFigure, [1 1]);
mainGrid.RowHeight = {'1x'};
mainGrid.ColumnWidth = {'1x'};

app.TabGroup.Parent = mainGrid;
app.TabGroup.Layout.Row = 1;
app.TabGroup.Layout.Column = 1;
% ===== Primary Tab Grid =====
primaryGrid = uigridlayout(app.PrimaryTab, [2 2]);

primaryGrid.RowHeight = {150, '1x'};   % Panel small, tables big
primaryGrid.ColumnWidth = {'1x','1x'};

% Panel top full width
app.Panel.Parent = primaryGrid;
app.Panel.Layout.Row = 1;
app.Panel.Layout.Column = [1 2];

% Temp left
app.TempTable.Parent = primaryGrid;
app.TempTable.Layout.Row = 2;
app.TempTable.Layout.Column = 1;

% Voltage right
app.VoltageTable.Parent = primaryGrid;
app.VoltageTable.Layout.Row = 2;
app.VoltageTable.Layout.Column = 2;
% ===== Summary Tab Grid =====
summaryGrid = uigridlayout(app.SummaryTab, [1 2]);

summaryGrid.ColumnWidth = {'2x','1x'};

app.SegmentTable.Parent = summaryGrid;
app.SegmentTable.Layout.Row = 1;
app.SegmentTable.Layout.Column = 1;

app.ICTempTable.Parent = summaryGrid;
app.ICTempTable.Layout.Row = 1;
app.ICTempTable.Layout.Column = 2;

    % Δεν ξεκινάμε timers εδώ.
    % Θα ξεκινήσουν στο ConnectButtonValueChanged όταν γίνει connect.


        end

        % Cell edit callback: TempTable
        function TempTableCellEdit(app, event)
          indices = event.Indices;   % [row, col]
    newValue = event.NewData;

    row = indices(1);
    col = indices(2);

    % Αποθήκευση της νέας τιμής στο matrix TEMPS
    if row >= 1 && row <= 12 && col >= 1 && col <= 12
        app.TEMPS(row, col) = newValue;
    end

    % Προαιρετικό: ενημέρωση GUI αμέσως μετά την αλλαγή
    app.TempTable.Data = app.TEMPS; 
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
        value = app.ConnectButton.Value;
            if value == 1
                % ===== ΣΥΝΔΕΣΗ =====
                try
                    port = strtrim(app.PortField.Value);
                    useWifi = startsWith(lower(port),"wifi") || startsWith(lower(port),"udp");
                    if isempty(port)
                        app.StatusLabel.Text = "No COM port";
                        app.ConnectButton.Value = 0;
                        return;
                    end
                    if useWifi
    try
        app.udpRx = udpport("datagram","IPV4","LocalPort",app.wifiLocalPort);
        app.wifiEnabled = true;

        start(app.wifiTimer);
        if strcmp(app.guiTimer.Running,"off")
            start(app.guiTimer);
        end

        app.connected = true;
        app.StatusLabel.Text = "Connected (WiFi UDP)";
        return;  % μην πάει στο serial
    catch ME
        app.connected = false;
        app.StatusLabel.Text = "WiFi Error";
        app.ErrorLabel.Text  = ME.message;
        app.ConnectButton.Value = 0;
        return;
    end
end


                    baud_uart = 9600;
                    app.ser = serialport(port, baud_uart, "Timeout", 0.1);
                    configureTerminator(app.ser, "CR");
                    flush(app.ser);

                    % Stop + timestamps
                    writeline(app.ser, "C");
                    writeline(app.ser, "L1");
                    pause(0.2);

                    % CAN bitrate
                    switch app.BaudDropdownDropDown.Value
                        case "125kbit/s"
                            can_cmd = "S4";
                        case "250kbit/s"
                            can_cmd = "S5";
                        case "500kbit/s"
                            can_cmd = "S6";
                        case "1000kbit/s"
                            can_cmd = "S8";
                        otherwise
                            can_cmd = "S6";
                    end

                    writeline(app.ser, can_cmd);
                    writeline(app.ser, "L1");
                    pause(0.2);

                    % Open CAN
                    writeline(app.ser, "O");
                    writeline(app.ser, "L1");
                    pause(0.2);

                    % Αρχικό frame προς BMS
                    balanceFlag = app.BalanceCheckBox.Value;
                    init = sprintf("T1502%s%d1", app.strHex, balanceFlag);
                    writeline(app.ser, init);
                    writeline(app.ser, "L1");

                    % Timers
                    start(app.readTimer);
                    if strcmp(app.guiTimer.Running, "off")
                        start(app.guiTimer);
                    end
                    if strcmp(app.hbTimer.Running, "off")
                       start(app.hbTimer);
                   end


                    app.connected = true;
                    app.StatusLabel.Text = "Connected";

                catch ME
                    app.connected = false;
                    app.StatusLabel.Text = "Serial Error";
                    app.ErrorLabel.Text  = ME.message;
                    app.ConnectButton.Value = 0;
                    try
                        if ~isempty(app.ser)
                            clear app.ser
                        end
                    end
                end

            else
                % ===== ΑΠΟΣΥΝΔΕΣΗ =====
                app.connected = false;

                if strcmp(app.readTimer.Running,"on")
                    stop(app.readTimer);
                end
                if strcmp(app.hbTimer.Running,"on")
                   stop(app.hbTimer);
                end

                try
                    if ~isempty(app.ser)
                        clear app.ser
                    end
                end

                app.StatusLabel.Text = "Disconnected";
            end
            % stop wifi timer
try
    if ~isempty(app.wifiTimer) && isvalid(app.wifiTimer) && strcmp(app.wifiTimer.Running,"on")
        stop(app.wifiTimer);
    end
catch
end

% clear udp
try
    if ~isempty(app.udpRx)
        clear app.udpRx
    end
catch
end
app.wifiEnabled = false;

        end

        % ChargeButton: ξεκινά / σταματά φόρτιση
        function ChargeButtonValueChanged(app, event)

            if ~app.connected
                app.ErrorLabel.Text = "Not connected";
                app.ChargeButton.Value = 0;
                return;
            end

            curr = app.ChargeCurrentFieldEditField.Value;

            if isnan(curr) || curr <= 0 || curr > 6
                app.ErrorLabel.Text = "Charge current must be 0–6 A";
                app.ChargeButton.Value = 0;
                return;
            end

            balanceFlag = app.BalanceCheckBox.Value;

            if app.ChargeButton.Value == 1
                % ===== ΕΝΑΡΞΗ ΦΟΡΤΙΣΗΣ =====
                app.chargingState = 1;

                rawVal     = int32(curr * 10) + 100;   % ίδιο mapping με Python
                app.strHex = upper(dec2hex(rawVal, 2));
                on_off     = '0';   % 0 => ON

                cmd = sprintf("T1502%s%d%s", app.strHex, balanceFlag, on_off);

                try
                    writeline(app.ser, cmd);
                    writeline(app.ser, "L1");
                    app.StatusLabel.Text = sprintf("Charging %.1f A", curr);
                    app.ErrorLabel.Text  = "";
                catch ME
                    app.ErrorLabel.Text    = ME.message;
                    app.StatusLabel.Text   = "Charge cmd error";
                    app.ChargeButton.Value = 0;
                    app.chargingState      = 0;
                end
            else
                % ===== ΣΤΑΜΑΤΗΜΑ ΦΟΡΤΙΣΗΣ (από χρήστη) =====
                app.chargingState = 0;
                on_off = '1';   % 1 => OFF
                cmd = sprintf("T1502%s%d%s", app.strHex, balanceFlag, on_off);

                try
                    writeline(app.ser, cmd);
                    writeline(app.ser, "L1");
                    app.StatusLabel.Text = "Charging OFF";
                catch ME
                    app.ErrorLabel.Text  = ME.message;
                    app.StatusLabel.Text = "Charge cmd error";
                end
            end
       
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        

    % ===== 1) Σταμάτησε & διέγραψε timers =====
    try
        if ~isempty(app.readTimer) && isvalid(app.readTimer)
            stop(app.readTimer);
            delete(app.readTimer);
        end
    end

    try
        if ~isempty(app.guiTimer) && isvalid(app.guiTimer)
            stop(app.guiTimer);
            delete(app.guiTimer);
        end
    end

    try
        if ~isempty(app.hbTimer) && isvalid(app.hbTimer)
            stop(app.hbTimer);
            delete(app.hbTimer);
        end
    end
try
    if ~isempty(app.wifiTimer) && isvalid(app.wifiTimer)
        stop(app.wifiTimer);
        delete(app.wifiTimer);
    end
end

    % ===== 2) Κλείσε serial =====
    try
        if ~isempty(app.ser)
            clear app.ser
        end
    end

    % ===== 3) Κλείσε το app (ΤΕΛΟΣ) =====
    closeCsv(app);

    delete(app);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PrimaryTab = uitab(app.TabGroup);
            app.PrimaryTab.Title = 'PrimaryTab';
            app.Panel = uipanel(app.PrimaryTab);
            app.ConnectButton = uibutton(app.Panel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = {'Connect'; ''};
            app.ChargeButton = uibutton(app.Panel, 'state');
            app.ChargeButton.Text = {'Charge'; ''};
            app.PortFieldEditFieldLabel = uilabel(app.Panel);
            app.PortFieldEditFieldLabel.Text = 'PortField';
            app.PortField = uieditfield(app.Panel, 'text');
            app.BaudDropdownDropDownLabel = uilabel(app.Panel);
            app.BaudDropdownDropDownLabel.Text = 'BaudDropdown';
            app.BaudDropdownDropDown = uidropdown(app.Panel);
            app.BaudDropdownDropDown.Items = {'', '500kbit/s'};
            app.BaudDropdownDropDown.Value = '500kbit/s';
            app.ChargeCurrentFieldEditFieldLabel = uilabel(app.Panel);
            app.ChargeCurrentFieldEditFieldLabel.Text = 'ChargeCurrentField';
            app.ChargeCurrentFieldEditField = uieditfield(app.Panel, 'numeric');
            app.BalanceCheckBox = uicheckbox(app.Panel);
            app.BalanceCheckBox.Text = {'Balance'; ''};
            app.FloatCheckBox = uicheckbox(app.Panel);
            app.FloatCheckBox.Text = 'Float';
            app.LogCSVCheckBox = uicheckbox(app.Panel);
            app.LogCSVCheckBox.Text = 'Log CSV';
            app.StatusLabel = uilabel(app.Panel);
            app.StatusLabel.Text = 'StatusLabel';
            app.PackVoltLabel = uilabel(app.Panel);
            app.PackVoltLabel.Text = 'PackVoltLabel';
            app.AvgVoltLabel = uilabel(app.Panel);
            app.AvgVoltLabel.Text = 'AvgVoltLabel';
            app.MinVoltLabel = uilabel(app.Panel);
            app.MinVoltLabel.Text = 'MinVoltLabel';
            app.MaxVoltLabel = uilabel(app.Panel);
            app.MaxVoltLabel.Text = 'MaxVoltLabel';
            app.DeltaVoltLabel = uilabel(app.Panel);
            app.DeltaVoltLabel.Text = 'DeltaVoltLabel';
            app.MinTempLabel = uilabel(app.Panel);
            app.MinTempLabel.Text = 'MinTempLabel';
            app.MaxTempLabel = uilabel(app.Panel);
            app.MaxTempLabel.Text = 'MaxTempLabel';
            app.TSCurrentLabel = uilabel(app.Panel);
            app.TSCurrentLabel.Text = 'TSCurrentLabel';
            app.ChargerCurrentLabel = uilabel(app.Panel);
            app.ChargerCurrentLabel.Text = 'ChargerCurrentLabel';
            app.ICMaxTempLabel = uilabel(app.Panel);
            app.ICMaxTempLabel.Text = 'ICMaxTempLabel';
            app.ChargerStatusLabel = uilabel(app.Panel);
            app.ChargerStatusLabel.Text = 'ChargerStatusLabel';
            app.ErrorLabel = uilabel(app.Panel);
            app.ErrorLabel.Text = 'ErrorLabel';
            app.HideDeltaButton = uibutton(app.Panel, 'state');
            app.HideDeltaButton.Text = 'Δ';
            app.VoltageTable = uitable(app.PrimaryTab);
            app.VoltageTable.ColumnName = {'cell1'; 'cell2'; 'cell3'; 'cell4'; 'cell5'; 'cell6'; 'cell7'; 'cell8'; 'cell9'; 'cell10'; 'cell11'; 'cell12'};
            app.VoltageTable.RowName = {'IC1,IC2,IC3,IC4,IC5,IC6,IC7,IC8,IC9,IC10,IC11,IC12'; ''};
            app.TempTable = uitable(app.PrimaryTab);
            app.TempTable.ColumnName = {'temp'; 'Τ2'; 'Τ3'; 'Τ4'; 'Τ5'; 'Τ6'; 'Τ7'; 'Τ8'; 'Τ9'; 'Τ10'; 'Τ11'; 'Τ12'};
            app.TempTable.RowName = {'1:12'; ''};
            app.TempTable.CellEditCallback = createCallbackFcn(app, @TempTableCellEdit, true);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'SummaryTab';
            app.SegmentTable = uitable(app.SummaryTab);
            app.SegmentTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'; 'Column 10'};
            app.SegmentTable.RowName = {};
            app.ICTempTable = uitable(app.SummaryTab);
            app.ICTempTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column  5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'; 'Column 10'};
            app.ICTempTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bmsMatlab1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn3)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end